classdef hsi_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ReflectanceProcessingTODOTab  matlab.ui.container.Tab
        CoralLabellingTab             matlab.ui.container.Tab
        RunStep2Button                matlab.ui.control.Button
        Step2TextArea                 matlab.ui.control.TextArea
        Step2TextAreaLabel            matlab.ui.control.Label
        RunStep1Button                matlab.ui.control.Button
        Step1TextArea                 matlab.ui.control.TextArea
        Step1TextAreaLabel            matlab.ui.control.Label
        HSIFileTextArea               matlab.ui.control.TextArea
        SelectHSIFileButton           matlab.ui.control.Button
        CoralLabellingUIAxes          matlab.ui.control.UIAxes
    end

    % Author: Jon Kok (j.kok@aims.gov.au)
    % Last modified: 11/01/2022
    properties (Access = private)
        ui_folder_path
        
        hsi_folder_path
        hsi_filename
        hsi_file_full_path

        tank_hsi
        tank_rgb
        tank_rgb_cropped
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ui_folder_path = [pwd filesep];
        end

        % Button pushed function: RunStep1Button
        function RunStep1ButtonPushed(app, event)
            if isempty(app.HSIFileTextArea.Value{1})
                uialert(app.UIFigure, 'HSI file source cannot be empty.', 'Warning', 'Icon', 'warning');
            else
                dialog_box = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading HSI Data','Indeterminate','on');
                app.tank_hsi = hypercubeMyFun(app.hsi_file_full_path);
                app.tank_rgb = colorize(app.tank_hsi,'Method','rgb','ContrastStretching',true);
                imshow(app.tank_rgb,'Parent',app.CoralLabellingUIAxes);
                close(dialog_box)
            end
        end

        % Button pushed function: SelectHSIFileButton
        function SelectHSIFileButtonPushed(app, event)
            app.SelectHSIFileButton.Enable = 'off'; 
            [file, folder_path] = uigetfile([app.ui_folder_path '*.hdr']);
            app.SelectHSIFileButton.Enable = 'on';
            if ~isequal(file,0)
                app.hsi_folder_path = folder_path;
                app.hsi_filename = file;
                app.hsi_file_full_path = [folder_path file];
                app.HSIFileTextArea.Value = app.hsi_file_full_path;
                app.ui_folder_path = folder_path;
            end
        end

        % Button pushed function: RunStep2Button
        function RunStep2ButtonPushed(app, event)
            %%%%% crop away above circle target
            [centers, radii] = imfindcircles(app.tank_rgb,[25 75]);
            calibration_target_column = round(centers(2) + radii(1));
            app.tank_rgb_cropped = imcrop(app.tank_rgb,[1 calibration_target_column size(app.tank_rgb,2) size(app.tank_rgb,1)]);
            imshow(app.tank_rgb_cropped,'Parent',app.CoralLabellingUIAxes);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReflectanceProcessingTODOTab = uitab(app.TabGroup);
            app.ReflectanceProcessingTODOTab.Title = 'Reflectance Processing (TODO)';
            app.CoralLabellingTab = uitab(app.TabGroup);
            app.CoralLabellingTab.Title = 'Coral Labelling';
            app.CoralLabellingUIAxes = uiaxes(app.CoralLabellingTab);
            app.CoralLabellingUIAxes.XTick = [];
            app.CoralLabellingUIAxes.YTick = [];
            app.CoralLabellingUIAxes.ZTick = [];
            app.SelectHSIFileButton = uibutton(app.CoralLabellingTab, 'push');
            app.SelectHSIFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHSIFileButtonPushed, true);
            app.SelectHSIFileButton.Text = 'Select HSI File';
            app.HSIFileTextArea = uitextarea(app.CoralLabellingTab);
            app.Step1TextAreaLabel = uilabel(app.CoralLabellingTab);
            app.Step1TextAreaLabel.Text = 'Step 1:';
            app.Step1TextArea = uitextarea(app.CoralLabellingTab);
            app.Step1TextArea.Value = {'Load HSI data and verify correct dataset is loaded. '};
            app.RunStep1Button = uibutton(app.CoralLabellingTab, 'push');
            app.RunStep1Button.ButtonPushedFcn = createCallbackFcn(app, @RunStep1ButtonPushed, true);
            app.RunStep1Button.Text = 'Run Step 1';
            app.Step2TextAreaLabel = uilabel(app.CoralLabellingTab);
            app.Step2TextAreaLabel.Text = 'Step 2:';
            app.Step2TextArea = uitextarea(app.CoralLabellingTab);
            app.Step2TextArea.Value = {'Remove calibration targets'};
            app.RunStep2Button = uibutton(app.CoralLabellingTab, 'push');
            app.RunStep2Button.ButtonPushedFcn = createCallbackFcn(app, @RunStep2ButtonPushed, true);
            app.RunStep2Button.Text = 'Run Step 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hsi_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end